classdef finalapp_MasterCopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        YieldStrengthEditField          matlab.ui.control.NumericEditField
        YieldStrengthEditFieldLabel     matlab.ui.control.Label
        SUBMITElementPropertiesButton   matlab.ui.control.Button
        NodeafterelementEditFieldLabel  matlab.ui.control.Label
        NodeafterelementEditField       matlab.ui.control.NumericEditField
        NodebeforeelementEditFieldLabel  matlab.ui.control.Label
        NodebeforeelementEditField      matlab.ui.control.NumericEditField
        ElementNumberEditField_2        matlab.ui.control.NumericEditField
        ElementNumberEditField_2Label   matlab.ui.control.Label
        ElementNodesLabel               matlab.ui.control.Label
        SUBMITButton                    matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NumberofElementsinTOTALEditFieldLabel  matlab.ui.control.Label
        DirectionofForceDropDownLabel   matlab.ui.control.Label
        SUBMITSUPPORTINFOButton         matlab.ui.control.Button
        TypeofSupportDropDown           matlab.ui.control.DropDown
        TypeofSupportDropDownLabel      matlab.ui.control.Label
        YoungsModulusEditFieldLabel     matlab.ui.control.Label
        AreaofCrossSectionEditFieldLabel  matlab.ui.control.Label
        ForcesValueEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        NodeNumberEditField_3           matlab.ui.control.NumericEditField
        NodeNumberEditField_3Label      matlab.ui.control.Label
        NodeNumberEditField_2Label      matlab.ui.control.Label
        SUBMITALLButton                 matlab.ui.control.Button
        SUBMITPropertiesButton          matlab.ui.control.Button
        NumberofElementsinTOTALEditField  matlab.ui.control.NumericEditField
        YoungsModulusEditField          matlab.ui.control.NumericEditField
        AreaofCrossSectionEditField     matlab.ui.control.NumericEditField
        ElementNumberEditField          matlab.ui.control.NumericEditField
        ElementNumberEditFieldLabel     matlab.ui.control.Label
        SUBMITFORCEButton               matlab.ui.control.Button
        DirectionofForceDropDown        matlab.ui.control.DropDown
        ForcesValueEditField            matlab.ui.control.NumericEditField
        NodeNumberEditField_2           matlab.ui.control.NumericEditField
        SUBMITCoordinatesButton         matlab.ui.control.Button
        YCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditField            matlab.ui.control.NumericEditField
        NodeNumberEditField             matlab.ui.control.NumericEditField
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        NumberofNodesinTOTALEditField   matlab.ui.control.NumericEditField
        NumberofNodesinTOTALEditFieldLabel  matlab.ui.control.Label
        ForcesactingonNodesLabel        matlab.ui.control.Label
        SupportsinStructurewrtNodeLabel  matlab.ui.control.Label
        GeometricPropertiesLabel        matlab.ui.control.Label
        NodeCoordinatesLabel            matlab.ui.control.Label
        STRUCTURALANALYSISLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        num_nodes
        xcood = 0
        ycood = 0
        nodecood
        coods
        nodef
        force
        forceval
        dropdownf
        nodebefore = 0
        nodeafter = 0
        elemnum
        elemnodes
        elem
        totalelem
        area
        ymod
        supportnum
        nodedof
        ys=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofNodesinTOTALEditField
        function NumberofNodesinTOTALEditFieldValueChanged(app, event)
            value = app.NumberofNodesinTOTALEditField.Value;
            app.num_nodes = value;
            app.force = zeros(2*app.num_nodes,1);
            app.nodedof = zeros(2*app.num_nodes,1);
            app.coods = zeros(app.num_nodes,2);
        end

        % Value changed function: XCoordinateEditField
        function XCoordinateEditFieldValueChanged(app, event)
            value = app.XCoordinateEditField.Value;
            app.xcood=value;
        end

        % Value changed function: YCoordinateEditField
        function YCoordinateEditFieldValueChanged(app, event)
            value = app.YCoordinateEditField.Value;
            app.ycood=value;
        end

        % Button pushed function: SUBMITCoordinatesButton
        function SUBMITCoordinatesButtonPushed(app, event)
            app.coods(app.nodecood,1)=app.xcood;
            app.coods(app.nodecood,2)=app.ycood;

        end

        % Value changed function: NodeNumberEditField
        function NodeNumberEditFieldValueChanged(app, event)
            value = app.NodeNumberEditField.Value;
            app.nodecood = value;
        end

        % Value changed function: NodeNumberEditField_2
        function NodeNumberEditField_2ValueChanged(app, event)
            value = app.NodeNumberEditField_2.Value;
            app.nodef= value;
        end

        % Value changed function: ForcesValueEditField
        function ForcesValueEditFieldValueChanged(app, event)
            value = app.ForcesValueEditField.Value;
            app.forceval = value;
        end

        % Button pushed function: SUBMITFORCEButton
        function SUBMITFORCEButtonPushed(app, event)
            switch app.DirectionofForceDropDown.Value
                case 'Horizontal'
                    app.force(((app.nodef)*2)-1) = app.forceval;
                otherwise
                    app.force((app.nodef)*2) = app.forceval;
            end
        end

        % Value changed function: ElementNumberEditField
        function ElementNumberEditFieldValueChanged(app, event)
            value = app.ElementNumberEditField.Value;
            app.elem = value;
        end

        % Button pushed function: SUBMITPropertiesButton
        function SUBMITPropertiesButtonPushed(app, event)
            app.area(app.elem)=app.AreaofCrossSectionEditField.Value;
            app.ymod(app.elem)=app.YoungsModulusEditField.Value;

        end

        % Value changed function: NumberofElementsinTOTALEditField
        function NumberofElementsinTOTALEditFieldValueChanged(app, event)
            value = app.NumberofElementsinTOTALEditField.Value;
            app.totalelem = value;
            app.elemnodes = zeros(app.totalelem,2);
            app.area = zeros(app.totalelem,1);
            app.ymod = zeros(app.totalelem,1);
        end

        % Button pushed function: SUBMITALLButton
        function SUBMITALLButtonPushed(app, event)
            for i = 1:app.totalelem
                app.area(i)=app.AreaofCrossSectionEditField.Value;
                app.ymod(i)=app.YoungsModulusEditField.Value;
                app.ys(i)=app.YieldStrengthEditField.Value;
            end
        end

        % Value changed function: NodeNumberEditField_3
        function NodeNumberEditField_3ValueChanged(app, event)
            value = app.NodeNumberEditField_3.Value;
            app.supportnum = value;
        end

        % Button pushed function: SUBMITSUPPORTINFOButton
        function SUBMITSUPPORTINFOButtonPushed(app, event)
            switch app.TypeofSupportDropDown.Value
                case 'Roller Joint'
                    app.nodedof((app.supportnum)*2) = 1;
                otherwise
                    app.nodedof(((app.supportnum)*2)-1) = 1;
                    app.nodedof((app.supportnum)*2) = 1;
            end

        end

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            ele_dof=[2*app.elemnodes(:,1)-1,2*app.elemnodes(:,1),2*app.elemnodes(:,2)-1,2*app.elemnodes(:,2)];
            displacement=zeros(2*app.num_nodes,1);
            stiffness=zeros(2*app.num_nodes);
            fixednodes = find(app.nodedof);
            overys = zeros(app.totalelem,1);

            for e=1:app.totalelem
                L=sqrt((app.coods(app.elemnodes(e,2),1)-app.coods(app.elemnodes(e,1),1))^2+(app.coods(app.elemnodes(e,2),2)-app.coods(app.elemnodes(e,1),2))^2);
                C=(app.coods(app.elemnodes(e,2),1)-app.coods(app.elemnodes(e,1),1))/L;
                S=(app.coods(app.elemnodes(e,2),2)-app.coods(app.elemnodes(e,1),2))/L;
                k=app.area(e)*app.ymod(e)/L*[C^2, C*S, -C^2, -C*S; C*S, S^2, -C*S, -S^2; -C^2, -C*S, C^2, C*S; -C*S, -S^2, C*S, S^2];
                for i=1:4
                    for j=1:4
                        stiffness(ele_dof(e,i),ele_dof(e,j))=stiffness(ele_dof(e,i),ele_dof(e,j))+k(i,j);
                    end
                end
            end

            known_f_a=(1:2*app.num_nodes)';
            for i=1:size(fixednodes,1)
                known_f_a(fixednodes(i))=0;
            end

            new_known_f_a = nonzeros(known_f_a);
            dis_new=zeros(size(new_known_f_a,1),1);
            force_new=zeros(size(new_known_f_a,1),1);
            stiff_new=zeros(size(new_known_f_a,1));

            for i=1:size(new_known_f_a,1)
                dis_new(i,1)=displacement(new_known_f_a(i,1),1);
                force_new(i,1)=app.force(new_known_f_a(i,1),1);
                for j=1:size(new_known_f_a,1)
                    stiff_new(i,j)=stiffness(new_known_f_a(i,1),new_known_f_a(j,1));
                end
            end

            dis_new=stiff_new\force_new;
            for i=1:size(new_known_f_a,1)
                displacement(new_known_f_a(i,1),1)=dis_new(i,1);
            end

            inforces=zeros(app.totalelem,1);

            stress = inforces./app.area;

            for e=1:app.totalelem
                L=sqrt((app.coods(app.elemnodes(e,2),1)-app.coods(app.elemnodes(e,1),1))^2+(app.coods(app.elemnodes(e,2),2)-app.coods(app.elemnodes(e,1),2))^2);
                C=(app.coods(app.elemnodes(e,2),1)-app.coods(app.elemnodes(e,1),1))/L;
                S=(app.coods(app.elemnodes(e,2),2)-app.coods(app.elemnodes(e,1),2))/L;
                inforces(e)=(app.area(e)*app.ymod(e)/L)*[-C -S C S]*[displacement(ele_dof(e,1));displacement(ele_dof(e,2));displacement(ele_dof(e,3));displacement(ele_dof(e,4))];
                stress = inforces./app.area;
                if stress(e)>app.ys(e)
                    overys(e)=true;
                end
            end

            assignin("base","displacement",displacement)
            assignin("base","internalforces",inforces)
            assignin("base","yieldstrengthexceeded",overys)
            assignin("base","stress",stress)
        end

        % Value changed function: ElementNumberEditField_2
        function ElementNumberEditField_2ValueChanged(app, event)
            value = app.ElementNumberEditField_2.Value;
            app.elemnum = value;
        end

        % Value changed function: NodebeforeelementEditField
        function NodebeforeelementEditFieldValueChanged(app, event)
            value = app.NodebeforeelementEditField.Value;
            app.nodebefore = value;
        end

        % Value changed function: NodeafterelementEditField
        function NodeafterelementEditFieldValueChanged(app, event)
            value = app.NodeafterelementEditField.Value;
            app.nodeafter = value;
        end

        % Button pushed function: SUBMITElementPropertiesButton
        function SUBMITElementPropertiesButtonPushed(app, event)
            app.elemnodes(app.elemnum,1)=app.nodebefore;
            app.elemnodes(app.elemnum,2)=app.nodeafter;
        end

        % Value changed function: YieldStrengthEditField
        function YieldStrengthEditFieldValueChanged(app, event)
            value = app.YieldStrengthEditField.Value;
            app.ys = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STRUCTURALANALYSISLabel = uilabel(app.UIFigure);
            app.STRUCTURALANALYSISLabel.Text = 'STRUCTURAL ANALYSIS';
            app.NodeCoordinatesLabel = uilabel(app.UIFigure);
            app.NodeCoordinatesLabel.Text = 'Node Coordinates';
            app.GeometricPropertiesLabel = uilabel(app.UIFigure);
            app.GeometricPropertiesLabel.Text = 'Geometric Properties';
            app.SupportsinStructurewrtNodeLabel = uilabel(app.UIFigure);
            app.SupportsinStructurewrtNodeLabel.Text = 'Supports in Structure w.r.t Node';
            app.ForcesactingonNodesLabel = uilabel(app.UIFigure);
            app.ForcesactingonNodesLabel.Text = 'Forces acting on Nodes';
            app.NumberofNodesinTOTALEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNodesinTOTALEditFieldLabel.Text = 'Number of Nodes in TOTAL';
            app.NumberofNodesinTOTALEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNodesinTOTALEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNodesinTOTALEditFieldValueChanged, true);
            app.NodeNumberEditFieldLabel = uilabel(app.UIFigure);
            app.NodeNumberEditFieldLabel.Text = 'Node Number';
            app.NodeNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.NodeNumberEditField.ValueChangedFcn = createCallbackFcn(app, @NodeNumberEditFieldValueChanged, true);
            app.XCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.XCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordinateEditFieldValueChanged, true);
            app.YCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @YCoordinateEditFieldValueChanged, true);
            app.SUBMITCoordinatesButton = uibutton(app.UIFigure, 'push');
            app.SUBMITCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITCoordinatesButtonPushed, true);
            app.SUBMITCoordinatesButton.Text = 'SUBMIT Coordinates';
            app.NodeNumberEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NodeNumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @NodeNumberEditField_2ValueChanged, true);
            app.ForcesValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForcesValueEditField.ValueChangedFcn = createCallbackFcn(app, @ForcesValueEditFieldValueChanged, true);
            app.DirectionofForceDropDown = uidropdown(app.UIFigure);
            app.DirectionofForceDropDown.Items = {'Horizontal', 'Vertical'};
            app.DirectionofForceDropDown.Value = 'Horizontal';
            app.SUBMITFORCEButton = uibutton(app.UIFigure, 'push');
            app.SUBMITFORCEButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITFORCEButtonPushed, true);
            app.SUBMITFORCEButton.Text = 'SUBMIT FORCE';
            app.ElementNumberEditFieldLabel = uilabel(app.UIFigure);
            app.ElementNumberEditFieldLabel.Text = 'Element Number';
            app.ElementNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElementNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ElementNumberEditFieldValueChanged, true);
            app.AreaofCrossSectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YoungsModulusEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofElementsinTOTALEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofElementsinTOTALEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsinTOTALEditFieldValueChanged, true);
            app.SUBMITPropertiesButton = uibutton(app.UIFigure, 'push');
            app.SUBMITPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITPropertiesButtonPushed, true);
            app.SUBMITPropertiesButton.Text = 'SUBMIT Properties';
            app.SUBMITALLButton = uibutton(app.UIFigure, 'push');
            app.SUBMITALLButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITALLButtonPushed, true);
            app.SUBMITALLButton.Text = 'SUBMIT ALL';
            app.NodeNumberEditField_2Label = uilabel(app.UIFigure);
            app.NodeNumberEditField_2Label.Text = 'Node Number';
            app.NodeNumberEditField_3Label = uilabel(app.UIFigure);
            app.NodeNumberEditField_3Label.Text = 'Node Number';
            app.NodeNumberEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.NodeNumberEditField_3.ValueChangedFcn = createCallbackFcn(app, @NodeNumberEditField_3ValueChanged, true);
            app.XCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.YCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.ForcesValueEditFieldLabel = uilabel(app.UIFigure);
            app.ForcesValueEditFieldLabel.Text = 'Forces Value';
            app.AreaofCrossSectionEditFieldLabel = uilabel(app.UIFigure);
            app.AreaofCrossSectionEditFieldLabel.Text = 'Area of Cross Section';
            app.YoungsModulusEditFieldLabel = uilabel(app.UIFigure);
            app.YoungsModulusEditFieldLabel.Text = 'Young''s Modulus';
            app.TypeofSupportDropDownLabel = uilabel(app.UIFigure);
            app.TypeofSupportDropDownLabel.Text = 'Type of Support';
            app.TypeofSupportDropDown = uidropdown(app.UIFigure);
            app.TypeofSupportDropDown.Items = {'Roller Joint', 'Pin Joint'};
            app.TypeofSupportDropDown.Value = 'Roller Joint';
            app.SUBMITSUPPORTINFOButton = uibutton(app.UIFigure, 'push');
            app.SUBMITSUPPORTINFOButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITSUPPORTINFOButtonPushed, true);
            app.SUBMITSUPPORTINFOButton.Text = 'SUBMIT SUPPORT INFO';
            app.DirectionofForceDropDownLabel = uilabel(app.UIFigure);
            app.DirectionofForceDropDownLabel.Text = 'Direction of Force';
            app.NumberofElementsinTOTALEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofElementsinTOTALEditFieldLabel.Text = 'Number of Elements in TOTAL';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'X&Y coordinates to be distance from Origin Node '; '(Maintain the same units throughout)'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Use Submit all if geometric properties '; 'are the same for all the values'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Submit the individual node or element properties for all sections and repeat for the next nodes/elements';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.ElementNodesLabel = uilabel(app.UIFigure);
            app.ElementNodesLabel.Text = 'Element Nodes';
            app.ElementNumberEditField_2Label = uilabel(app.UIFigure);
            app.ElementNumberEditField_2Label.Text = 'Element Number';
            app.ElementNumberEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ElementNumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @ElementNumberEditField_2ValueChanged, true);
            app.NodebeforeelementEditField = uieditfield(app.UIFigure, 'numeric');
            app.NodebeforeelementEditField.ValueChangedFcn = createCallbackFcn(app, @NodebeforeelementEditFieldValueChanged, true);
            app.NodebeforeelementEditFieldLabel = uilabel(app.UIFigure);
            app.NodebeforeelementEditFieldLabel.Text = 'Node before element';
            app.NodeafterelementEditField = uieditfield(app.UIFigure, 'numeric');
            app.NodeafterelementEditField.ValueChangedFcn = createCallbackFcn(app, @NodeafterelementEditFieldValueChanged, true);
            app.NodeafterelementEditFieldLabel = uilabel(app.UIFigure);
            app.NodeafterelementEditFieldLabel.Text = 'Node after element';
            app.SUBMITElementPropertiesButton = uibutton(app.UIFigure, 'push');
            app.SUBMITElementPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITElementPropertiesButtonPushed, true);
            app.SUBMITElementPropertiesButton.Text = {'SUBMIT Element'; 'Properties'};
            app.YieldStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.YieldStrengthEditFieldLabel.Text = 'Yield Strength';
            app.YieldStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.YieldStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @YieldStrengthEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp_MasterCopy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end